classdef Mala_CheckCoordinates < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout3                  matlab.ui.container.GridLayout
        GridLayout9                  matlab.ui.container.GridLayout
        DeletepointsinboxButton      matlab.ui.control.StateButton
        DeletepointButton            matlab.ui.control.StateButton
        GridLayout8                  matlab.ui.container.GridLayout
        SmoothcoordinateswithmovmeanButton  matlab.ui.control.Button
        WindowpointsEditField        matlab.ui.control.NumericEditField
        WindowpointsEditFieldLabel   matlab.ui.control.Label
        RestoreallprofilesButton     matlab.ui.control.Button
        RestorecurrentprofileButton  matlab.ui.control.Button
        SelectprofileinmapButton     matlab.ui.control.StateButton
        GridLayout7                  matlab.ui.container.GridLayout
        ProfileLabel                 matlab.ui.control.Label
        GridLayout6                  matlab.ui.container.GridLayout
        LayoutButtonGroup            matlab.ui.container.ButtonGroup
        verticalButton               matlab.ui.control.RadioButton
        horizontalButton             matlab.ui.control.RadioButton
        ProfileList                  matlab.ui.control.ListBox
        ListBoxLabel                 matlab.ui.control.Label
        AddpointButton               matlab.ui.control.StateButton
        MovepointButton              matlab.ui.control.StateButton
        LoadposfilesButton           matlab.ui.control.Button
        SaveallchangesButton         matlab.ui.control.Button
        GridLayout2                  matlab.ui.container.GridLayout
        Map                          matlab.ui.control.UIAxes
        Northings                    matlab.ui.control.UIAxes
        Eastings                     matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Handles % plot handles
        profilenum % profile numbers
        Data % data
        currProfile % current profile
        clickactive =0; % flag for clicking
        foldername % foldername
        allaxes % all axes
        highlightActive=0; % flag if connection between plots is active or not
        drawbox=0; % flag for drawing a box with points inside for deletion
        waitForClick=0; % flag for waiting for next click
        dragPoint=0; % flag for moving point
    end

    methods (Access = private)


        function resetAllHighlights(app)
            if isfield(app.Handles,'M_highlight')
                delete(app.Handles.M_highlight);
            end
            if isfield(app.Handles,'E_highlight')
                delete(app.Handles.E_highlight);
            end
            if isfield(app.Handles,'N_highlight')
                delete(app.Handles.N_highlight);
            end
        end
        
        function plot_new(app)
            % UPDATE: plot current profile new after changes:
            i=find(app.currProfile==app.profilenum);
            % Map:
            app.Handles.Scatters(i).XData=app.Data.data{i}(:,2);
            app.Handles.Scatters(i).YData=app.Data.data{i}(:,3);
            app.Handles.Scatters(i).CData=app.Data.data{i}(:,4);
            app.Handles.proflines(i).XData=app.Data.data{i}(:,2);
            app.Handles.proflines(i).YData=app.Data.data{i}(:,3);
            % Easting/Northing:
            LayoutChanged(app);
        end
        
        function resetAllPlots(app)
            app.highlightActive=0;
            % reset map and plot everything new (this also resets the
            % toolbar in the axes):
            cla(app.Map, 'reset');
            cla(app.Eastings, 'reset');
            cla(app.Northings, 'reset');
            drawnow;

            % Map:
            app.Map.NextPlot='add';
            app.Map.DataAspectRatio=[1 1 1];
            for i=1:numel(app.Data.list)
                app.Handles.Scatters(i)=scatter(app.Map,app.Data.data{i}(:,2),app.Data.data{i}(:,3),40,zeros(size(app.Data.data{i}(:,1)))+app.profilenum(i),'fill');
                app.Handles.proflines(i)=plot(app.Map,app.Data.data{i}(:,2),app.Data.data{i}(:,3),'k');
                app.Handles.Scatters(i).MarkerEdgeColor='none';
            end
            app.Map.CLim=[min(app.profilenum) max(app.profilenum)];
            app.Map.XGrid='on';
            app.Map.YGrid='on';
            cb=colorbar(app.Map);
            ylabel(cb,'Profile number');

            app.Handles.Scatters(app.currProfile==app.profilenum).MarkerEdgeColor='r'; 

            % Easting/Northing:
            app.Eastings.NextPlot='add';
            app.Northings.NextPlot='add';
            app.Handles.Eastings=plot(app.Eastings,app.Data.data{1}(:,1),app.Data.data{1}(:,2),'k','Marker','+','Linewidth',2);
            app.Handles.Northings=plot(app.Northings,app.Data.data{1}(:,1),app.Data.data{1}(:,3),'k','Marker','+','Linewidth',2);
            app.Eastings.XGrid='on';
            app.Eastings.YGrid='on';
            app.Northings.XGrid='on';
            app.Northings.YGrid='on';

            % check which layout:
            LayoutChanged(app);

            app.Eastings.Tag='Eastings';
            app.Map.Tag='Map';
            app.Northings.Tag='Northings';

            app.highlightActive=1;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startup_fcn(app)
            app.allaxes={app.Map, app.Eastings, app.Northings};
        end

        % Button pushed function: LoadposfilesButton
        function Load_pos_files(app, event)
            % get folder name
            if ispc
                if exist('temp.temp') % read last opened folder from temp.temp
                    fid=fopen('temp.temp','r');
                    if fid~=-1
                        fn=textscan(fid,'%s');
                    else
                        fn{1}=[];
                    end
                    fclose(fid);
                    if ~isempty(fn{1})
                        foldername=uigetdir(fn{1}{1},'Select rSlicer folder');
                    else
                        foldername=uigetdir([],'Select rSlicer folder');
                    end
                    fid=fopen('temp.temp','wt');
                    fprintf(fid,'%s',foldername);
                    fclose(fid);
                else
                    foldername=uigetdir([],'Select rSlicer folder');

                    fid=fopen('temp.temp','wt');
                    fprintf(fid,'%s',foldername);
                    fclose(fid);
                end
            else
                if exist('.temp.temp') % read last opened folder from temp.temp
                    fid=fopen('.temp.temp','r');
                    fn=textscan(fid,'%s');
                    fclose(fid);
                    if ~isempty(fn{1})
                        foldername=uigetdir(fn{1}{1},'Select rSlicer folder');
                    else
                        foldername=uigetdir([],'Select rSlicer folder');
                    end
                else
                    foldername=uigetdir([],'Select rSlicer folder');
                end

                fid=fopen('.temp.temp','wt');
                fprintf(fid,'%s',foldername);
                fclose(fid);
            end
            app.UIFigure.Visible='off';
            app.UIFigure.Visible='on';

            app.UIFigure.Pointer='watch';
            app.foldername=foldername;

            % plot map:
            list=dir(fullfile(foldername,'*.pos'));
            % get profile numbers:
            names = {list.name}.';
            keepMask = cellfun(@(x) x(1) ~= '.', names);
            tokens = regexp(names(keepMask), '_(\d{3})_G', 'tokens', 'once');
            app.profilenum = cellfun(@(t) str2double(t), tokens);
            list=list(keepMask); % delete files starting with '.'
            app.Data.list=list;

            if ~exist(fullfile(foldername,'original_pos'),'dir')
                mkdir(fullfile(foldername,'original_pos'));
            end

            % Plot in map:
            app.Map.NextPlot='add';
            app.Map.DataAspectRatio=[1 1 1];
            for i=1:numel(list)
                % read pos file:
                fid=fopen(fullfile(foldername,list(i).name),'r');
                temp=textscan(fid,'%f%f%f%f','Headerlines',1);
                fclose(fid);

                app.Data.data{i}=[temp{1} temp{3} temp{2} temp{4}]; % trnum, easting, northing, height

                app.Handles.Scatters(i)=scatter(app.Map,app.Data.data{i}(:,2),app.Data.data{i}(:,3),40,zeros(size(app.Data.data{i}(:,1)))+app.profilenum(i),'fill');
                app.Handles.proflines(i)=plot(app.Map,app.Data.data{i}(:,2),app.Data.data{i}(:,3),'k');
                app.Handles.Scatters(i).MarkerEdgeColor='none';

                % save original file in new folder:
                if ~exist(fullfile(foldername,'original_pos',list(i).name),'file')
                    copyfile(fullfile(foldername,list(i).name),fullfile(foldername,'original_pos',list(i).name))
                end
            end
            app.Map.CLim=[min(app.profilenum) max(app.profilenum)];
            cb=colorbar(app.Map);
            ylabel(cb,'Profile number');

            app.Handles.Scatters(1).MarkerEdgeColor='r';

            % Plot first profile in other axes:
            app.Eastings.NextPlot='add';
            app.Northings.NextPlot='add';
            app.Handles.Eastings=plot(app.Eastings,app.Data.data{1}(:,1),app.Data.data{1}(:,2),'k','Marker','+','Linewidth',2);
            app.Handles.Northings=plot(app.Northings,app.Data.data{1}(:,1),app.Data.data{1}(:,3),'k','Marker','+','Linewidth',2);
            app.currProfile=app.profilenum(1);

            % check which layout is best:
            all_data = vertcat(app.Data.data{:});  % [trnum, easting, northing, height]
            if abs(max(all_data(:,2))-min(all_data(:,2)))>=abs(max(all_data(:,3))-min(all_data(:,3)))
                % horizontal layout:
                app.horizontalButton.Value=1;
            else
                % vertical layout:
                app.verticalButton.Value=1;
            end
            LayoutChanged(app);

            % fill profile list box:
            app.ProfileList.Items=string(app.profilenum);
            app.ProfileList.Value=string(app.currProfile);

            app.UIFigure.Pointer='arrow';

            app.highlightActive=1;
        end

        % Value changed function: DeletepointButton
        function deletePoint(app, event)
            value = app.DeletepointButton.Value;
            for i=1:3
                zoom(app.allaxes{i}, 'off');
                t(i) = axtoolbar(app.allaxes{i});
            end
            if value==1
                app.clickactive=3;
                for i=1:3
                    t(i).Visible = 'off';
                end
            else
                app.clickactive=0;
                resetAllPlots(app);
            end
        end

        % Value changed function: DeletepointsinboxButton
        function deleteBoxPoints(app, event)
            value = app.DeletepointsinboxButton.Value;
            for i=1:3
                zoom(app.allaxes{i}, 'off');
                t(i) = axtoolbar(app.allaxes{i});
            end
            if value==1
                % disable highlights:
                app.highlightActive=0;
                app.clickactive=4;
                for i=1:3
                    t(i).Visible = 'off';
                end
            else
                app.clickactive=0;
                resetAllPlots(app);
            end
        end

        % Value changed function: MovepointButton
        function movePoint(app, event)
            value = app.MovepointButton.Value;
            for i=1:3
                zoom(app.allaxes{i}, 'off');
                t(i) = axtoolbar(app.allaxes{i});
            end
            if value==1
                app.clickactive=2;
            else
                app.clickactive=0;
                resetAllPlots(app);
            end
        end

        % Button pushed function: SaveallchangesButton
        function SaveChanges(app, event)
            % Save all pos-files:
             for i=1:numel(app.Data.list)
                % write pos file:
                fid=fopen(fullfile(app.foldername,app.Data.list(i).name),'wt');
                fprintf(fid,'UNITS:m\n');
                fprintf(fid,'%d\t%f\t%f\t%f\n',app.Data.data{i}(:,[1 3 2 4])'); % change easting/northing to original order: N/E
                fclose(fid);
             end
            disp(['All pos-files saved in ',app.foldername])
        end

        % Selection changed function: LayoutButtonGroup
        function LayoutChanged(app, event)
            selectedButton = app.LayoutButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'vertical')
                app.GridLayout2.ColumnWidth={'3x','1x','1x'};
                app.GridLayout2.RowHeight={'1x'};
                % Easting/northing plot:
                app.Handles.Eastings.XData=app.Data.data{app.currProfile==app.profilenum}(:,2);
                app.Handles.Eastings.YData=app.Data.data{app.currProfile==app.profilenum}(:,1);
                app.Handles.Northings.XData=app.Data.data{app.currProfile==app.profilenum}(:,3);
                app.Handles.Northings.YData=app.Data.data{app.currProfile==app.profilenum}(:,1);
                xlabel(app.Eastings,'Easting [m]');
                ylabel(app.Eastings,'Sample number');
                xlabel(app.Northings,'Northing [m]');
                ylabel(app.Northings,'Sample number');
                app.Eastings.Layout.Row = 1;
                app.Eastings.Layout.Column = 2;
                app.Northings.Layout.Row = 1;
                app.Northings.Layout.Column = 3;
            else
                % horizontal layout:
                app.GridLayout2.RowHeight={'3x','1x','1x'};
                app.GridLayout2.ColumnWidth={'1x'};
                % Easting/northing plot:
                app.Handles.Eastings.XData=app.Data.data{app.currProfile==app.profilenum}(:,1);
                app.Handles.Eastings.YData=app.Data.data{app.currProfile==app.profilenum}(:,2);
                app.Handles.Northings.XData=app.Data.data{app.currProfile==app.profilenum}(:,1);
                app.Handles.Northings.YData=app.Data.data{app.currProfile==app.profilenum}(:,3);
                ylabel(app.Eastings,'Easting [m]');
                xlabel(app.Eastings,'Sample number');
                ylabel(app.Northings,'Northing [m]');
                xlabel(app.Northings,'Sample number');
                app.Eastings.Layout.Row = 2;
                app.Eastings.Layout.Column = 1;
                app.Northings.Layout.Row = 3;
                app.Northings.Layout.Column = 1;
            end
        end

        % Value changed function: ProfileList
        function ProfileChanged(app, event)
            value = app.ProfileList.Value;
            app.currProfile=str2num(value);
            if strcmp(app.LayoutButtonGroup.SelectedObject.Text,'vertical')
                app.Handles.Eastings.XData=app.Data.data{app.currProfile==app.profilenum}(:,2);
                app.Handles.Eastings.YData=app.Data.data{app.currProfile==app.profilenum}(:,1);
                app.Handles.Northings.XData=app.Data.data{app.currProfile==app.profilenum}(:,3);
                app.Handles.Northings.YData=app.Data.data{app.currProfile==app.profilenum}(:,1);
            elseif strcmp(app.LayoutButtonGroup.SelectedObject.Text,'horizontal')
                app.Handles.Eastings.XData=app.Data.data{app.currProfile==app.profilenum}(:,1);
                app.Handles.Eastings.YData=app.Data.data{app.currProfile==app.profilenum}(:,2);
                app.Handles.Northings.XData=app.Data.data{app.currProfile==app.profilenum}(:,1);
                app.Handles.Northings.YData=app.Data.data{app.currProfile==app.profilenum}(:,3);
            end
            % highlight profile in map:
            set(app.Handles.Scatters, 'MarkerEdgeColor', 'none');
            app.Handles.Scatters(app.currProfile==app.profilenum).MarkerEdgeColor='r';
            xlim(app.Eastings, 'auto'); ylim(app.Eastings, 'auto');
            xlim(app.Northings, 'auto'); ylim(app.Northings, 'auto');
        end

        % Value changed function: SelectprofileinmapButton
        function SelectProfileMap(app, event)
            value = app.SelectprofileinmapButton.Value;
            if value==1
                % enable picking in map:
                app.clickactive=1;
            else
                app.clickactive=0;
            end
        end

        % Window button down function: UIFigure
        function ButtonDown(app, event)
      %%% select profile in map
            if app.clickactive==1 
                cp=event.Source.CurrentAxes.CurrentPoint(1,1:2);
                dista = cellfun(@(x) sqrt((x(:,2) - cp(1,1)).^2 + (x(:,3) - cp(1,2)).^2),app.Data.data,'UniformOutput',false);
                mindist=cellfun(@(x) min(x),dista);
                app.currProfile=app.profilenum(mindist==min(mindist));
                app.ProfileList.Value=string(app.currProfile);
                ProfileChanged(app);
                scroll(app.ProfileList, app.ProfileList.Value);
                app.SelectprofileinmapButton.Value=0;
                SelectProfileMap(app);
            end

       %%% move point:
            if app.clickactive==2 && app.dragPoint==0
                cp=event.Source.CurrentAxes.CurrentPoint(1,1:2);
                % check if click in axes or outside on button:
                xlim=event.Source.CurrentAxes.XLim;
                ylim=event.Source.CurrentAxes.YLim;
                if cp(1)>=xlim(1) && cp(1)<=xlim(2) && cp(2)>=ylim(1) && cp(2)<=ylim(2)
                    if strcmp(event.Source.CurrentAxes.Tag,'Map')
                        dista = cellfun(@(x) sqrt((x(:,2) - cp(1,1)).^2 + (x(:,3) - cp(1,2)).^2),app.Data.data,'UniformOutput',false);
                        mindist=cellfun(@(x) min(x),dista);
                        profnum=app.profilenum(mindist==min(mindist)); % profile number
                        if profnum==app.currProfile
                            [~,idx]=min(dista{profnum==app.profilenum}); % idx is point number on this profile

                            app.Data.DragPointIndex = idx;
                            app.dragPoint=1;
                        else
                            disp('Moving of points only works on current profile!')
                        end
                    elseif strcmp(event.Source.CurrentAxes.Tag,'Eastings')
                        xdata=app.Handles.Eastings.XData;
                        ydata=app.Handles.Eastings.YData;
                        dista = sqrt((xdata - cp(1,1)).^2 + (ydata - cp(1,2)).^2);
                        [~,idx]=min(dista);
                        app.Data.DragPointIndex = idx;
                        app.dragPoint=2;
                    elseif strcmp(event.Source.CurrentAxes.Tag,'Northings')
                        xdata=app.Handles.Northings.XData;
                        ydata=app.Handles.Northings.YData;
                        dista = sqrt((xdata - cp(1,1)).^2 + (ydata - cp(1,2)).^2);
                        [~,idx]=min(dista);
                        app.Data.DragPointIndex = idx;
                        app.dragPoint=3;
                    end
                end
            end
            
         %%% delete point
            if app.clickactive==3 
                cp=event.Source.CurrentAxes.CurrentPoint(1,1:2);
                % check if click in axes or outside on button:
                xlim=event.Source.CurrentAxes.XLim;
                ylim=event.Source.CurrentAxes.YLim;
                if cp(1)>=xlim(1) && cp(1)<=xlim(2) && cp(2)>=ylim(1) && cp(2)<=ylim(2)
                    if strcmp(event.Source.CurrentAxes.Tag,'Map')
                        dista = cellfun(@(x) sqrt((x(:,2) - cp(1,1)).^2 + (x(:,3) - cp(1,2)).^2),app.Data.data,'UniformOutput',false);
                        mindist=cellfun(@(x) min(x),dista);
                        profnum=app.profilenum(mindist==min(mindist)); % profile number
                        if profnum==app.currProfile
                            [~,idx]=min(dista{profnum==app.profilenum}); % idx is point number on this profile

                            app.Data.data{profnum==app.profilenum}(idx,:)=[];
                            plot_new(app);
                        else
                            disp('Delete points only works on current profile!')
                        end
                    elseif strcmp(event.Source.CurrentAxes.Tag,'Eastings')
                        xdata=app.Handles.Eastings.XData;
                        ydata=app.Handles.Eastings.YData;
                        dista = sqrt((xdata - cp(1,1)).^2 + (ydata - cp(1,2)).^2);
                        [~,idx]=min(dista);
                        app.Data.data{app.currProfile==app.profilenum}(idx,:)=[];
                        plot_new(app);
                    elseif strcmp(event.Source.CurrentAxes.Tag,'Northings')
                        xdata=app.Handles.Northings.XData;
                        ydata=app.Handles.Northings.YData;
                        dista = sqrt((xdata - cp(1,1)).^2 + (ydata - cp(1,2)).^2);
                        [~,idx]=min(dista);
                        app.Data.data{app.currProfile==app.profilenum}(idx,:)=[];
                        plot_new(app);
                    end
                end
            end

        %%% draw box and delete points in it
            if app.clickactive==4 && app.waitForClick==0
                % get current point:
                cp=event.Source.CurrentAxes.CurrentPoint(1,1:2);
                % check if click in axes or outside on button:
                xlim=event.Source.CurrentAxes.XLim;
                ylim=event.Source.CurrentAxes.YLim;
                if cp(1)>=xlim(1) && cp(1)<=xlim(2) && cp(2)>=ylim(1) && cp(2)<=ylim(2)
                    % get start point:
                    if strcmp(event.Source.CurrentAxes.Tag,'Map')
                        app.Data.boxstart=cp;
                        app.Data.boxhandleMap=plot(app.Map,cp(1),cp(2),'r-','Linewidth',2);
                        app.drawbox=1; % activate button motion for drawing of box, 1 means in map
                    elseif strcmp(event.Source.CurrentAxes.Tag,'Eastings')
                        app.Data.boxstart=cp;
                        app.Data.boxhandleE=plot(app.Eastings,cp(1),cp(2),'r-','LineWidth',2);
                        app.drawbox=2; % activate button motion for drawing of box, 2 means in Eastings
                    elseif strcmp(event.Source.CurrentAxes.Tag,'Northings')
                        app.Data.boxstart=cp;
                        app.Data.boxhandleN=plot(app.Northings,cp(1),cp(2),'r-','LineWidth',2);
                        app.drawbox=3; % activate button motion for drawing of box, 3 means in Northings
                    end
                end
            elseif app.clickactive==4 && app.waitForClick==1
                % end click for selection box in map
                cp=event.Source.CurrentAxes.CurrentPoint(1,1:2);
                app.Data.boxende=cp;
                if app.drawbox==1 % in map:
                    % find all points of current profile in this box:
                    ind=app.Data.data{app.currProfile==app.profilenum}(:,2)>=min(app.Data.boxstart(1),app.Data.boxende(1)) & ...
                        app.Data.data{app.currProfile==app.profilenum}(:,2)<=max(app.Data.boxstart(1),app.Data.boxende(1)) & ...
                        app.Data.data{app.currProfile==app.profilenum}(:,3)>=min(app.Data.boxstart(2),app.Data.boxende(2)) & ...
                        app.Data.data{app.currProfile==app.profilenum}(:,3)<=max(app.Data.boxstart(2),app.Data.boxende(2));
                elseif app.drawbox==2 || app.drawbox==3 % in E or N:
                    % find all points of current profile in this box:
                    if app.verticalButton.Value==1
                        trnum=app.Data.boxhandleE.YData;
                    else
                        trnum=app.Data.boxhandleE.XData;
                    end
                    ind=app.Data.data{app.currProfile==app.profilenum}(:,1)>=min(trnum) & app.Data.data{app.currProfile==app.profilenum}(:,1)<=max(trnum);
                end
                % delete this data:
                app.Data.data{app.currProfile==app.profilenum}(ind,:)=[];
                % delete box:
                delete(app.Data.boxhandleMap);
                delete(app.Data.boxhandleE);
                delete(app.Data.boxhandleN);
                app.Data=rmfield(app.Data,{'boxhandleMap', 'boxhandleE', 'boxhandleN'});
                %plot_new(app);
                app.waitForClick=0;
                app.drawbox=0;
                % reset button:
                app.DeletepointsinboxButton.Value=0;
                % activate hghlights again:
                app.highlightActive=1;
                deleteBoxPoints(app);
            end
        end

        % Window button motion function: UIFigure
        function ButtonMotion(app, event)
            %%% highlight corresponding points in plots:
            if isfield(app.Handles,'Scatters') && app.highlightActive==1
                cp = app.UIFigure.CurrentPoint;

                [~,k] = getAxesUnderMouse(app, cp);

                % Test which axes:
                if k==1
                    axUnderMouse = app.Map; 
                    xdata = app.Handles.Scatters(app.currProfile==app.profilenum).XData;
                    ydata = app.Handles.Scatters(app.currProfile==app.profilenum).YData;
                    distlim=1;
                elseif k==2
                    axUnderMouse = app.Eastings; 
                    xdata = app.Handles.Eastings.XData; 
                    ydata = app.Handles.Eastings.YData;
                    distlim=5;
                elseif k==3
                    axUnderMouse = app.Northings; 
                    xdata = app.Handles.Northings.XData; 
                    ydata = app.Handles.Northings.YData;
                    distlim=5;
                else
                    return;
                end

                % Reset aller Highlights
                resetAllHighlights(app);

                % Finde nächsten Punkt in aktuellem Axes
                [idx, dist] = findClosestPoint(axUnderMouse,xdata,ydata);
                if dist > distlim, return; end  % in m

                % Highlight in allen drei Axes am selben Index
                app.Handles.M_highlight=plot(app.Map, app.Handles.Scatters(app.currProfile==app.profilenum).XData(idx), app.Handles.Scatters(app.currProfile==app.profilenum).YData(idx), ...
                    'ro', 'MarkerSize', 10, 'MarkerFaceColor', 'r', 'LineWidth', 2);
                app.Handles.E_highlight=plot(app.Eastings, app.Handles.Eastings.XData(idx), app.Handles.Eastings.YData(idx), ...
                    'ro', 'MarkerSize', 10, 'MarkerFaceColor', 'r', 'LineWidth', 2);
                app.Handles.N_highlight=plot(app.Northings, app.Handles.Northings.XData(idx), app.Handles.Northings.YData(idx), ...
                    'ro', 'MarkerSize', 10, 'MarkerFaceColor', 'r', 'LineWidth', 2);
            end


            %%% move point:
            if app.dragPoint==1 % Map
                cp = app.Map.CurrentPoint(1,1:2); % point in map
                idx = app.Data.DragPointIndex;

                % Daten aktualisieren
                app.Handles.Scatters(app.currProfile==app.profilenum).XData(idx)=cp(1);
                app.Handles.Scatters(app.currProfile==app.profilenum).YData(idx)=cp(2);
                app.Handles.proflines(app.currProfile==app.profilenum).XData(idx)=cp(1);
                app.Handles.proflines(app.currProfile==app.profilenum).YData(idx)=cp(2);

                if app.verticalButton.Value==1
                    app.Handles.Eastings.XData(idx)=cp(1);
                    app.Handles.Northings.XData(idx)=cp(2);
                else
                    app.Handles.Eastings.YData(idx)=cp(1);
                    app.Handles.Northings.YData(idx)=cp(2);
                end

                drawnow limitrate;
            elseif app.dragPoint==2 % Eastings
                cp = app.Eastings.CurrentPoint(1,1:2); % point in Eastings
                idx = app.Data.DragPointIndex;

                app.Handles.Eastings.XData(idx)=cp(1);
                app.Handles.Eastings.YData(idx)=cp(2);

                if app.verticalButton.Value==1
                    % Map
                    app.Handles.Scatters(app.currProfile==app.profilenum).XData(idx)=cp(1);
                    app.Handles.proflines(app.currProfile==app.profilenum).XData(idx)=cp(1);
                    % Northings
                    app.Handles.Northings.YData(idx)=cp(2);
                else
                    % Map
                    app.Handles.Scatters(app.currProfile==app.profilenum).XData(idx)=cp(2);
                    app.Handles.proflines(app.currProfile==app.profilenum).XData(idx)=cp(2);
                    % Northings
                    app.Handles.Northings.XData(idx)=cp(1);
                end

                drawnow limitrate;
            elseif app.dragPoint==3 % Northings
                cp = app.Northings.CurrentPoint(1,1:2); % point in Northings
                idx = app.Data.DragPointIndex;

                app.Handles.Northings.XData(idx)=cp(1);
                app.Handles.Northings.YData(idx)=cp(2);

                if app.verticalButton.Value==1
                    % Map
                    app.Handles.Scatters(app.currProfile==app.profilenum).YData(idx)=cp(1);
                    app.Handles.proflines(app.currProfile==app.profilenum).YData(idx)=cp(1);
                    % Eastings
                    app.Handles.Eastings.YData(idx)=cp(2);
                else
                    % Map
                    app.Handles.Scatters(app.currProfile==app.profilenum).YData(idx)=cp(2);
                    app.Handles.proflines(app.currProfile==app.profilenum).YData(idx)=cp(2);
                    % Eastings
                    app.Handles.Eastings.XData(idx)=cp(1);
                end

                drawnow limitrate;
            end


            %%% draw box in map:
            if app.drawbox==1
                pos = app.Map.CurrentPoint(1,1:2);
                % draw box in map:
                app.Data.boxhandleMap.XData=[app.Data.boxstart(1) pos(1) pos(1) app.Data.boxstart(1) app.Data.boxstart(1)];
                app.Data.boxhandleMap.YData=[app.Data.boxstart(2) app.Data.boxstart(2) pos(2) pos(2) app.Data.boxstart(2)];
                % find trnums in box:
                ind=find(app.Data.data{app.currProfile==app.profilenum}(:,2)>=min(app.Data.boxstart(1),pos(1)) & ...
                    app.Data.data{app.currProfile==app.profilenum}(:,2)<=max(app.Data.boxstart(1),pos(1)) & ...
                    app.Data.data{app.currProfile==app.profilenum}(:,3)>=min(app.Data.boxstart(2),pos(2)) & ...
                    app.Data.data{app.currProfile==app.profilenum}(:,3)<=max(app.Data.boxstart(2),pos(2)));
                % also draw corresponding boxes in Eastings & Northings:
                trnum=[app.Data.data{app.currProfile==app.profilenum}(min(ind),1) app.Data.data{app.currProfile==app.profilenum}(max(ind),1)]+[-0.75 0.75]; % min/max tracenumber
                E=[app.Data.data{app.currProfile==app.profilenum}(min(ind),2) app.Data.data{app.currProfile==app.profilenum}(max(ind),2)]+[-2 2];
                N=[app.Data.data{app.currProfile==app.profilenum}(min(ind),3) app.Data.data{app.currProfile==app.profilenum}(max(ind),3)]+[-2 2];
                if ~isempty(E)
                    if ~isfield(app.Data,'boxhandleE')
                        if app.verticalButton==1
                            app.Data.boxhandleE=plot(app.Eastings,[E(1) E(2) E(2) E(1) E(1)],[trnum(1) trnum(1) trnum(2) trnum(2) trnum(1)],'r-','LineWidth',2);
                            app.Data.boxhandleN=plot(app.Northings,[N(1) N(2) N(2) N(1) N(1)],[trnum(1) trnum(1) trnum(2) trnum(2) trnum(1)],'r-','LineWidth',2);
                        else
                            app.Data.boxhandleE=plot(app.Eastings,[trnum(1) trnum(1) trnum(2) trnum(2) trnum(1)],[E(1) E(2) E(2) E(1) E(1)],'r-','LineWidth',2);
                            app.Data.boxhandleN=plot(app.Northings,[trnum(1) trnum(1) trnum(2) trnum(2) trnum(1)],[N(1) N(2) N(2) N(1) N(1)],'r-','LineWidth',2);
                        end
                    else
                        if app.verticalButton.Value==1
                            app.Data.boxhandleE.XData=[E(1) E(2) E(2) E(1) E(1)];
                            app.Data.boxhandleE.YData=[trnum(1) trnum(1) trnum(2) trnum(2) trnum(1)];
                            app.Data.boxhandleN.XData=[N(1) N(2) N(2) N(1) N(1)];
                            app.Data.boxhandleN.YData=[trnum(1) trnum(1) trnum(2) trnum(2) trnum(1)];
                        elseif app.horizontalButton.Value==1
                            app.Data.boxhandleE.YData=[E(1) E(2) E(2) E(1) E(1)];
                            app.Data.boxhandleE.XData=[trnum(1) trnum(1) trnum(2) trnum(2) trnum(1)];
                            app.Data.boxhandleN.YData=[N(1) N(2) N(2) N(1) N(1)];
                            app.Data.boxhandleN.XData=[trnum(1) trnum(1) trnum(2) trnum(2) trnum(1)];
                        end
                    end
                end
                app.waitForClick=1;
            elseif app.drawbox==2 % draw box in eastings:
                pos=app.Eastings.CurrentPoint(1,1:2);
                % draw box in eastings:
                if app.verticalButton.Value==1
                    app.Data.boxhandleE.XData=[app.Data.boxstart(1) pos(1) pos(1) app.Data.boxstart(1) app.Data.boxstart(1)];
                    app.Data.boxhandleE.YData=[app.Data.boxstart(2) app.Data.boxstart(2) pos(2) pos(2) app.Data.boxstart(2)];
                    % find trnums in box:
                    ind=find(app.Data.data{app.currProfile==app.profilenum}(:,1)>=min(app.Data.boxstart(2),pos(2)) & ...
                        app.Data.data{app.currProfile==app.profilenum}(:,1)<=max(app.Data.boxstart(2),pos(2)));
                    trnum=app.Data.data{app.currProfile==app.profilenum}(ind,1); % tracenumber
                    E=app.Data.data{app.currProfile==app.profilenum}(ind,2);
                    N=app.Data.data{app.currProfile==app.profilenum}(ind,3);
                    % draw in northings:
                    if ~isfield(app.Data,'boxhandleN') || isempty(app.Data.boxhandleN)
                        app.Data.boxhandleN=plot(app.Northings,[min(N)-2 max(N)+2 max(N)+2 min(N)-2 min(N)-2],[min(trnum)-0.75 min(trnum)-0.75 max(trnum)+0.75 max(trnum)+0.75 min(trnum)-0.75],'r-','LineWidth',2);
                    else
                        app.Data.boxhandleN.XData=[min(N)-2 max(N)+2 max(N)+2 min(N)-2 min(N)-2];
                        app.Data.boxhandleN.YData=[min(trnum)-0.75 min(trnum)-0.75 max(trnum)+0.75 max(trnum)+0.75 min(trnum)-0.75];
                    end
                    % draw in map:
                    if ~isfield(app.Data,'boxhandleMap') || isempty(app.Data.boxhandleMap)
                        app.Data.boxhandleMap=plot(app.Map,[min(E)-0.05 max(E)+0.05 max(E)+0.05 min(E)-0.05 min(E)-0.05],[min(N)-0.05 min(N)-0.05 max(N)+0.05 max(N)+0.05 min(N)-0.05],'k-','LineWidth',2);
                    else
                        app.Data.boxhandleMap.XData=[min(E)-0.05 max(E)+0.05 max(E)+0.05 min(E)-0.05 min(E)-0.05];
                        app.Data.boxhandleMap.YData=[min(N)-0.05 min(N)-0.05 max(N)+0.05 max(N)+0.05 min(N)-0.05];
                    end
                    app.waitForClick=1;
                else
                    app.Data.boxhandleE.XData=[app.Data.boxstart(1) pos(1) pos(1) app.Data.boxstart(1) app.Data.boxstart(1)];
                    app.Data.boxhandleE.YData=[app.Data.boxstart(2) app.Data.boxstart(2) pos(2) pos(2) app.Data.boxstart(2)];
                    % find trnums in box:
                    ind=find(app.Data.data{app.currProfile==app.profilenum}(:,1)>=min(app.Data.boxstart(1),pos(1)) & ...
                        app.Data.data{app.currProfile==app.profilenum}(:,1)<=max(app.Data.boxstart(1),pos(1)));
                    trnum=app.Data.data{app.currProfile==app.profilenum}(ind,1); % tracenumber
                    E=app.Data.data{app.currProfile==app.profilenum}(ind,2);
                    N=app.Data.data{app.currProfile==app.profilenum}(ind,3);
                    % draw in northings:
                    if ~isfield(app.Data,'boxhandleN') || isempty(app.Data.boxhandleN)
                        app.Data.boxhandleN=plot(app.Northings,[min(trnum)-0.75 min(trnum)-0.75 max(trnum)+0.75 max(trnum)+0.75 min(trnum)-0.75],[min(N)-2 max(N)+2 max(N)+2 min(N)-2 min(N)-2],'r-','LineWidth',2);
                    else
                        app.Data.boxhandleN.YData=[min(N)-2 max(N)+2 max(N)+2 min(N)-2 min(N)-2];
                        app.Data.boxhandleN.XData=[min(trnum)-0.75 min(trnum)-0.75 max(trnum)+0.75 max(trnum)+0.75 min(trnum)-0.75];
                    end
                    % draw in map:
                    if ~isfield(app.Data,'boxhandleMap') || isempty(app.Data.boxhandleMap)
                        app.Data.boxhandleMap=plot(app.Map,[min(E)-0.05 max(E)+0.05 max(E)+0.05 min(E)-0.05 min(E)-0.05],[min(N)-0.05 min(N)-0.05 max(N)+0.05 max(N)+0.05 min(N)-0.05],'k-','LineWidth',2);
                    else
                        app.Data.boxhandleMap.XData=[min(E)-0.05 max(E)+0.05 max(E)+0.05 min(E)-0.05 min(E)-0.05];
                        app.Data.boxhandleMap.YData=[min(N)-0.05 min(N)-0.05 max(N)+0.05 max(N)+0.05 min(N)-0.05];
                    end
                end
                app.waitForClick=1;
            elseif app.drawbox==3  % draw box in northings:
                pos=app.Northings.CurrentPoint(1,1:2);
                % draw box in northings:
                if app.verticalButton.Value==1
                    app.Data.boxhandleN.XData=[app.Data.boxstart(1) pos(1) pos(1) app.Data.boxstart(1) app.Data.boxstart(1)];
                    app.Data.boxhandleN.YData=[app.Data.boxstart(2) app.Data.boxstart(2) pos(2) pos(2) app.Data.boxstart(2)];
                    % find trnums in box:
                    ind=find(app.Data.data{app.currProfile==app.profilenum}(:,1)>=min(app.Data.boxstart(2),pos(2)) & ...
                        app.Data.data{app.currProfile==app.profilenum}(:,1)<=max(app.Data.boxstart(2),pos(2)));
                    trnum=app.Data.data{app.currProfile==app.profilenum}(ind,1); % tracenumber
                    E=app.Data.data{app.currProfile==app.profilenum}(ind,2);
                    N=app.Data.data{app.currProfile==app.profilenum}(ind,3);
                    % draw in eastings:
                    if ~isfield(app.Data,'boxhandleE') || isempty(app.Data.boxhandleE)
                        app.Data.boxhandleE=plot(app.Eastings,[min(E)-2 max(E)+2 max(E)+2 min(E)-2 min(E)-2],[min(trnum)-0.75 min(trnum)-0.75 max(trnum)+0.75 max(trnum)+0.75 min(trnum)-0.75],'r-','LineWidth',2);
                    else
                        app.Data.boxhandleE.XData=[min(E)-2 max(E)+2 max(E)+2 min(E)-2 min(E)-2];
                        app.Data.boxhandleE.YData=[min(trnum)-0.75 min(trnum)-0.75 max(trnum)+0.75 max(trnum)+0.75 min(trnum)-0.75];
                    end
                    % draw in map:
                    if ~isfield(app.Data,'boxhandleMap') || isempty(app.Data.boxhandleMap)
                        app.Data.boxhandleMap=plot(app.Map,[min(E)-0.05 max(E)+0.05 max(E)+0.05 min(E)-0.05 min(E)-0.05],[min(N)-0.05 min(N)-0.05 max(N)+0.05 max(N)+0.05 min(N)-0.05],'k-','LineWidth',2);
                    else
                        app.Data.boxhandleMap.XData=[min(E)-0.05 max(E)+0.05 max(E)+0.05 min(E)-0.05 min(E)-0.05];
                        app.Data.boxhandleMap.YData=[min(N)-0.05 min(N)-0.05 max(N)+0.05 max(N)+0.05 min(N)-0.05];
                    end
                    app.waitForClick=1;
                else
                    app.Data.boxhandleN.XData=[app.Data.boxstart(1) pos(1) pos(1) app.Data.boxstart(1) app.Data.boxstart(1)];
                    app.Data.boxhandleN.YData=[app.Data.boxstart(2) app.Data.boxstart(2) pos(2) pos(2) app.Data.boxstart(2)];
                    % find trnums in box:
                    ind=find(app.Data.data{app.currProfile==app.profilenum}(:,1)>=min(app.Data.boxstart(1),pos(1)) & ...
                        app.Data.data{app.currProfile==app.profilenum}(:,1)<=max(app.Data.boxstart(1),pos(1)));
                    trnum=app.Data.data{app.currProfile==app.profilenum}(ind,1); % tracenumber
                    E=app.Data.data{app.currProfile==app.profilenum}(ind,2);
                    N=app.Data.data{app.currProfile==app.profilenum}(ind,3);
                    % draw in eastings:
                    if ~isfield(app.Data,'boxhandleE') || isempty(app.Data.boxhandleE)
                        app.Data.boxhandleE=plot(app.Eastings,[min(trnum)-0.75 min(trnum)-0.75 max(trnum)+0.75 max(trnum)+0.75 min(trnum)-0.75],[min(E)-2 max(E)+2 max(E)+2 min(E)-2 min(E)-2],'r-','LineWidth',2);
                    else
                        app.Data.boxhandleE.YData=[min(E)-2 max(E)+2 max(E)+2 min(E)-2 min(E)-2];
                        app.Data.boxhandleE.XData=[min(trnum)-0.75 min(trnum)-0.75 max(trnum)+0.75 max(trnum)+0.75 min(trnum)-0.75];
                    end
                    % draw in map:
                    if ~isfield(app.Data,'boxhandleMap') || isempty(app.Data.boxhandleMap)
                        app.Data.boxhandleMap=plot(app.Map,[min(E)-0.05 max(E)+0.05 max(E)+0.05 min(E)-0.05 min(E)-0.05],[min(N)-0.05 min(N)-0.05 max(N)+0.05 max(N)+0.05 min(N)-0.05],'k-','LineWidth',2);
                    else
                        app.Data.boxhandleMap.XData=[min(E)-0.05 max(E)+0.05 max(E)+0.05 min(E)-0.05 min(E)-0.05];
                        app.Data.boxhandleMap.YData=[min(N)-0.05 min(N)-0.05 max(N)+0.05 max(N)+0.05 min(N)-0.05];
                    end
                end
                app.waitForClick=1;
            end


            %%% helper functions:
            function [ax,k,pos] = getAxesUnderMouse(app, cpFig)
                ax = [];
                gridLayout = app.Map.Parent; 
                % Figure → Grid Koordinaten umrechnen
                gridPos = gridLayout.Position;
                cpGrid = cpFig - [gridPos(1), gridPos(2)];  % Offset korrigieren

                candidates = [app.Map, app.Eastings, app.Northings];
                for k = 1:numel(candidates)
                    if ~isequal(candidates(k).Parent, gridLayout)
                        continue; 
                    end
                    pos = candidates(k).Position;
                    if cpGrid(1) >= pos(1) && cpGrid(1) <= pos(1)+pos(3) && ...
                            cpGrid(2) >= pos(2) && cpGrid(2) <= pos(2)+pos(4)
                        ax = candidates(k);
                        return;
                    end
                end
            end

            function tf = pointerInAxes(pos, ax)
                axPos = ax.Position;
                tf = pos(1) >= axPos(1) && pos(1) <= axPos(1)+axPos(3) && ...
                    pos(2) >= axPos(2) && pos(2) <= axPos(2)+axPos(4);
            end

            function [idx, minDist] = findClosestPoint(ax,xdata,ydata)
                cpo = ax.CurrentPoint;

                % Distanz zu allen Punkten
                dista = sqrt((xdata - cpo(1,1)).^2 + (ydata - cpo(1,2)).^2);
                [minDist, idx] = min(dista);
            end

        end

        % Window button up function: UIFigure
        function ButtonUp(app, event)
            %%% end of move point:
            if app.dragPoint==1 % Map
                cp = app.Map.CurrentPoint(1,1:2); % point in map
                idx = app.Data.DragPointIndex;

                % Daten aktualisieren
                app.Handles.Scatters(app.currProfile==app.profilenum).XData(idx)=cp(1);
                app.Handles.Scatters(app.currProfile==app.profilenum).YData(idx)=cp(2);
                app.Handles.proflines(app.currProfile==app.profilenum).XData(idx)=cp(1);
                app.Handles.proflines(app.currProfile==app.profilenum).YData(idx)=cp(2);

                if app.verticalButton.Value==1
                    app.Handles.Eastings.XData(idx)=cp(1);
                    app.Handles.Northings.XData(idx)=cp(2);
                else
                    app.Handles.Eastings.YData(idx)=cp(1);
                    app.Handles.Northings.YData(idx)=cp(2);
                end

                % set new point in data:
                app.Data.data{app.currProfile==app.profilenum}(idx,2:3)=cp;

                app.dragPoint = 0;

            elseif app.dragPoint==2 % Eastings:
                cp = app.Eastings.CurrentPoint(1,1:2); % point in Eastings
                idx = app.Data.DragPointIndex;

                app.Handles.Eastings.XData(idx)=cp(1);
                app.Handles.Eastings.YData(idx)=cp(2);

                if app.verticalButton.Value==1
                    % Map -> change Easting
                    app.Handles.Scatters(app.currProfile==app.profilenum).XData(idx)=cp(1);
                    app.Handles.proflines(app.currProfile==app.profilenum).XData(idx)=cp(1);
                    % Northings -> change sample number
                    app.Handles.Northings.YData(idx)=round(cp(2));

                    % set new point in data:
                    app.Data.data{app.currProfile==app.profilenum}(idx,2)=cp(1);
                    app.Data.data{app.currProfile==app.profilenum}(idx,1)=round(cp(2));
                else
                    % Map -> change Easting
                    app.Handles.Scatters(app.currProfile==app.profilenum).XData(idx)=cp(2);
                    app.Handles.proflines(app.currProfile==app.profilenum).XData(idx)=cp(2);
                    % Northings -> change sample number
                    app.Handles.Northings.XData(idx)=round(cp(1));

                    % set new point in data:
                    app.Data.data{app.currProfile==app.profilenum}(idx,2)=cp(2);
                    app.Data.data{app.currProfile==app.profilenum}(idx,1)=round(cp(1));
                end

                app.dragPoint = 0;

            elseif app.dragPoint==3 % Northings
                cp = app.Northings.CurrentPoint(1,1:2); % point in Northings
                idx = app.Data.DragPointIndex;

                app.Handles.Northings.XData(idx)=cp(1);
                app.Handles.Northings.YData(idx)=cp(2);

                if app.verticalButton.Value==1
                    % Map -> change northing
                    app.Handles.Scatters(app.currProfile==app.profilenum).YData(idx)=cp(1);
                    app.Handles.proflines(app.currProfile==app.profilenum).YData(idx)=cp(1);
                    % Eastings -> change sample number
                    app.Handles.Eastings.YData(idx)=round(cp(2));

                    % set new point in data:
                    app.Data.data{app.currProfile==app.profilenum}(idx,3)=cp(1);
                    app.Data.data{app.currProfile==app.profilenum}(idx,1)=round(cp(2));
                else
                    % Map -> change northing
                    app.Handles.Scatters(app.currProfile==app.profilenum).YData(idx)=cp(2);
                    app.Handles.proflines(app.currProfile==app.profilenum).YData(idx)=cp(2);
                    % Eastings  -> change sample number
                    app.Handles.Eastings.XData(idx)=round(cp(1));

                    % set new point in data:
                    app.Data.data{app.currProfile==app.profilenum}(idx,3)=cp(2);
                    app.Data.data{app.currProfile==app.profilenum}(idx,1)=round(cp(1));
                end

                app.dragPoint = 0;
            end
        end

        % Button pushed function: SmoothcoordinateswithmovmeanButton
        function SmoothCoords(app, event)
            w=app.WindowpointsEditField.Value;
            data=app.Data.data{app.currProfile==app.profilenum}(:,2:3); % E/N
            app.Data.data{app.currProfile==app.profilenum}(:,2:3)=movmean(data,w,1);
            plot_new(app);
        end

        % Button pushed function: RestorecurrentprofileButton
        function RestoreCurrentProfile(app, event)
            % read original pos file:
            i=find(app.currProfile==app.profilenum);
            fid=fopen(fullfile(app.foldername,'original_pos',app.Data.list(i).name),'r');
            temp=textscan(fid,'%f%f%f%f','Headerlines',1);
            fclose(fid);

            app.Data.data{i}=[temp{1} temp{3} temp{2} temp{4}]; % trnum, easting, northing, height
            plot_new(app);
        end

        % Button pushed function: RestoreallprofilesButton
        function RestoreAllProfiles(app, event)
            % read original pos files:
            for i=1:length(app.profilenum)
                fid=fopen(fullfile(app.foldername,'original_pos',app.Data.list(i).name),'r');
                temp=textscan(fid,'%f%f%f%f','Headerlines',1);
                fclose(fid);

                app.Data.data{i}=[temp{1} temp{3} temp{2} temp{4}]; % trnum, easting, northing, height
            end
            plot_new(app);
        end

        % Close request function: UIFigure
        function CloseFunction(app, event)
                answer = questdlg('Do you want to close the app? Unsaved changes will be lost!', ...
                    'Do you really want to close?','Quit app without saving', 'Save changes and close app', 'Cancel','Cancel');

                switch answer
                    case 'Save changes and close app'
                        app.SaveChanges(app);  
                        delete(app);

                    case 'Cancel'
                        return;  

                    case 'Quit app without saving'
                        delete(app); 
                end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseFunction, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @ButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @ButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @ButtonMotion, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Eastings = uiaxes(app.GridLayout2);
            title(app.Eastings, 'Eastings')
            xlabel(app.Eastings, 'Sample number')
            ylabel(app.Eastings, 'Easting [m]')
            zlabel(app.Eastings, 'Z')
            app.Eastings.Tag = 'Eastings';
            app.Northings = uiaxes(app.GridLayout2);
            title(app.Northings, 'Northings')
            xlabel(app.Northings, 'Sample number')
            ylabel(app.Northings, 'Northing [m]')
            zlabel(app.Northings, 'Z')
            app.Northings.Tag = 'Northings';
            app.Map = uiaxes(app.GridLayout2);
            title(app.Map, 'Map')
            xlabel(app.Map, 'Easting [m]')
            ylabel(app.Map, 'Northing [m]')
            zlabel(app.Map, 'Z')
            app.Map.Tag = 'Map';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.SaveallchangesButton = uibutton(app.GridLayout3, 'push');
            app.SaveallchangesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveChanges, true);
            app.SaveallchangesButton.Text = 'Save all changes';
            app.LoadposfilesButton = uibutton(app.GridLayout3, 'push');
            app.LoadposfilesButton.ButtonPushedFcn = createCallbackFcn(app, @Load_pos_files, true);
            app.LoadposfilesButton.Text = 'Load pos-files';
            app.MovepointButton = uibutton(app.GridLayout3, 'state');
            app.MovepointButton.ValueChangedFcn = createCallbackFcn(app, @movePoint, true);
            app.MovepointButton.Text = 'Move point';
            app.AddpointButton = uibutton(app.GridLayout3, 'state');
            app.AddpointButton.Text = 'Add point';
            app.GridLayout6 = uigridlayout(app.GridLayout3);
            app.ListBoxLabel = uilabel(app.GridLayout6);
            app.ListBoxLabel.Text = 'List Box';
            app.ProfileList = uilistbox(app.GridLayout6);
            app.ProfileList.Items = {};
            app.ProfileList.ValueChangedFcn = createCallbackFcn(app, @ProfileChanged, true);
            app.ProfileList.Value = {};
            app.LayoutButtonGroup = uibuttongroup(app.GridLayout6);
            app.LayoutButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LayoutChanged, true);
            app.LayoutButtonGroup.Title = 'Layout';
            app.horizontalButton = uiradiobutton(app.LayoutButtonGroup);
            app.horizontalButton.Text = 'horizontal';
            app.horizontalButton.Value = true;
            app.verticalButton = uiradiobutton(app.LayoutButtonGroup);
            app.verticalButton.Text = 'vertical';
            app.GridLayout7 = uigridlayout(app.GridLayout3);
            app.ProfileLabel = uilabel(app.GridLayout7);
            app.ProfileLabel.Text = 'Profile';
            app.SelectprofileinmapButton = uibutton(app.GridLayout3, 'state');
            app.SelectprofileinmapButton.ValueChangedFcn = createCallbackFcn(app, @SelectProfileMap, true);
            app.SelectprofileinmapButton.Text = 'Select profile in map';
            app.RestorecurrentprofileButton = uibutton(app.GridLayout3, 'push');
            app.RestorecurrentprofileButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreCurrentProfile, true);
            app.RestorecurrentprofileButton.Text = 'Restore current profile';
            app.RestoreallprofilesButton = uibutton(app.GridLayout3, 'push');
            app.RestoreallprofilesButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreAllProfiles, true);
            app.RestoreallprofilesButton.Text = 'Restore all profiles';
            app.GridLayout8 = uigridlayout(app.GridLayout3);
            app.WindowpointsEditFieldLabel = uilabel(app.GridLayout8);
            app.WindowpointsEditFieldLabel.Text = 'Window (points)';
            app.WindowpointsEditField = uieditfield(app.GridLayout8, 'numeric');
            app.WindowpointsEditField.Limits = [0 Inf];
            app.WindowpointsEditField.Value = 5;
            app.SmoothcoordinateswithmovmeanButton = uibutton(app.GridLayout8, 'push');
            app.SmoothcoordinateswithmovmeanButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothCoords, true);
            app.SmoothcoordinateswithmovmeanButton.Text = 'Smooth coordinates with movmean';
            app.GridLayout9 = uigridlayout(app.GridLayout3);
            app.DeletepointButton = uibutton(app.GridLayout9, 'state');
            app.DeletepointButton.ValueChangedFcn = createCallbackFcn(app, @deletePoint, true);
            app.DeletepointButton.Text = 'Delete point';
            app.DeletepointsinboxButton = uibutton(app.GridLayout9, 'state');
            app.DeletepointsinboxButton.ValueChangedFcn = createCallbackFcn(app, @deleteBoxPoints, true);
            app.DeletepointsinboxButton.Text = 'Delete points in box';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Mala_CheckCoordinates

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startup_fcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end